classdef coolingSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        AmbientTemperaturedegCEditFieldLabel  matlab.ui.control.Label
        AmbientTemperaturedegCEditField  matlab.ui.control.NumericEditField
        UITable                      matlab.ui.control.Table
        CoolingSimulationLabel       matlab.ui.control.Label
        TargetdegCminEditFieldLabel  matlab.ui.control.Label
        TargetdegCminEditField       matlab.ui.control.NumericEditField
        Switch                       matlab.ui.control.Switch
        TextArea                     matlab.ui.control.TextArea
        AddButton                    matlab.ui.control.Button
        RemoveButton                 matlab.ui.control.Button
        CalculateButton              matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        HoldonCheckBox               matlab.ui.control.CheckBox
        InfoLabel                    matlab.ui.control.Label
    end

    properties (Access = private)
        AmbientTemperature = 0; % Ambient temperature of the system
        legendArray = {}; % For identifying the different plots
        legendCounter = -1; % Keeps count of how many legends need to be printed
        plotStyles = ["k--", "k:", "k-.", "k-"]; % Varies the plot for ambient temperature
        plotCounter = -1; % Decides which plot type will be used
        percentage = 0.01; % Percentage for tolerance
    end
    
    methods (Access = private)
        
        function [] = resetTextArea(app)
            app.TextArea.Value = "";
        end
        
        function [] = drawSolution(app, tdata, ydata, t, duration)
            app.legendCounter = app.legendCounter + 2;
            app.plotCounter = app.plotCounter + 1;
            
            if app.HoldonCheckBox.Value == 0
                app.legendArray = {};
                app.legendCounter = 1;
                app.plotCounter = 0;
                hold(app.UIAxes, 'off')
                app.UIAxes.cla
            end
            
            plot(app.UIAxes, tdata, ydata)
            hold(app.UIAxes, 'on')
            yline(app.UIAxes, app.AmbientTemperature, app.plotStyles(1,mod(app.plotCounter, length(app.plotStyles))+1))
            
            [~,I] = max(abs(app.AmbientTemperature*ones(length(app.UITable.Data(:,2)),1) - app.UITable.Data(:,2)));
            diff = app.AmbientTemperature - app.UITable.Data(I,2);
            coefficient = 0.25; % A scaling coefficient for axis
            epsilon = 0.000001;
            
            if diff <= 0
                axis(app.UIAxes, [0 duration+epsilon app.AmbientTemperature-coefficient*abs(diff) app.AmbientTemperature+abs(diff)+1])
            else
                %coefficient = 0.5;
                axis(app.UIAxes, [0 duration+epsilon app.AmbientTemperature-abs(diff)-1 app.AmbientTemperature+coefficient*abs(diff)])
            end
                       
            app.legendArray{1,app.legendCounter} = [num2str(app.AmbientTemperature,3), ' + ', num2str(t(1),3), 'exp(-', num2str(t(2),3), 't) (degC)']; 
            app.legendArray{1,app.legendCounter+1} = ['Ambient temperature of ', num2str(app.AmbientTemperature,3), ' degC'];
            
            legend(app.UIAxes, app.legendArray)
          
        end
        
        function fun = NCL(app,t,tdata)
            fun = app.AmbientTemperature + t(1)*exp(-t(2)*tdata); % Newton's Cooling Law 
        end
        
        function [] = checkInput(app)
            % Checking for erroneous inputs
            for i=1:size(app.UITable.Data,2)
                for j=1:size(app.UITable.Data,1)
                    if isnan(app.UITable.Data(j,i))
                        app.TextArea.Value = "Please check data.";
                        app.AddButton.Enable = false;
                        app.CalculateButton.Enable = false;
                        return
                    end
                end
            end
            
            app.AddButton.Enable = true;
            app.CalculateButton.Enable = true;
            
        end
        
        function duration = getDuration(app,t)
            duration = 0;
            tolerance = app.percentage * app.AmbientTemperature; % Cool down up to 99% of ambient temperature
            maxDuration = 240; % corresponds to 4h
            while abs(NCL(app,t,duration) - app.AmbientTemperature) > tolerance
                if duration == maxDuration
                    break
                end
                
                duration = duration + 1;
                
            end
        end
        
        function [] = calculateSolution(app)
            x0 = [20; 0.05]; % Initial guess
            t = lsqcurvefit(@app.NCL, x0, app.UITable.Data(:,1), app.UITable.Data(:,2));
            
            duration = getDuration(app,t);
            tdata = linspace(0, duration);
            ydata = NCL(app,t,tdata);
            
            drawSolution(app, tdata, ydata, t, duration)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AmbientTemperaturedegCEditField.Value = 20;
            app.AmbientTemperaturedegCEditField.Limits = [0,Inf];
            app.AmbientTemperature = app.AmbientTemperaturedegCEditField.Value;
            
            app.UIAxes.Title.String = "Newton's Cooling Law";
            
            app.TargetdegCminEditField.Value = 25;
            
            app.TextArea.Editable = false;
            
            app.UITable.Data = [0 34; 16 27]; % Example data
            app.UITable.ColumnEditable = true;
            
            calculateSolution(app)
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            resetTextArea(app)
            app.RemoveButton.Enable = true;
            app.UITable.Data(end+1,:) = 0; % Add row with zeros
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            resetTextArea(app)
            
            if size(app.UITable.Data,1) > 2
                app.UITable.Data(end,:) = []; % Remove last row
            else
                app.RemoveButton.Enable = false;
                app.TextArea.Value = "Must have at least two data points.";
            end
            
            checkInput(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            resetTextArea(app)
            checkInput(app)
        end

        % Value changed function: AmbientTemperaturedegCEditField
        function AmbientTemperaturedegCEditFieldValueChanged(app, event)
            value = app.AmbientTemperaturedegCEditField.Value;
            app.AmbientTemperature = value;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            calculateSolution(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Temperature (degC)')
            app.AmbientTemperaturedegCEditFieldLabel = uilabel(app.UIFigure);
            app.AmbientTemperaturedegCEditFieldLabel.Text = 'Ambient Temperature (degC)';
            app.AmbientTemperaturedegCEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmbientTemperaturedegCEditField.ValueChangedFcn = createCallbackFcn(app, @AmbientTemperaturedegCEditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Time (min)'; 'Temperature (degC)'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CoolingSimulationLabel = uilabel(app.UIFigure);
            app.CoolingSimulationLabel.Text = 'Cooling Simulation';
            app.TargetdegCminEditFieldLabel = uilabel(app.UIFigure);
            app.TargetdegCminEditFieldLabel.Text = 'Target (degC / min)';
            app.TargetdegCminEditField = uieditfield(app.UIFigure, 'numeric');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Temperature', 'Time'};
            app.Switch.Value = 'Temperature';
            app.TextArea = uitextarea(app.UIFigure);
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.HoldonCheckBox = uicheckbox(app.UIFigure);
            app.HoldonCheckBox.Text = 'Hold on';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = 'Info:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coolingSimulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end